classdef MultiAnimalTimers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AboutButton                   matlab.ui.control.Button
        savedirectoryLabel            matlab.ui.control.Label
        ChangesavedirectoryButton     matlab.ui.control.Button
        noteEditField_5               matlab.ui.control.EditField
        noteEditField_5Label          matlab.ui.control.Label
        AddtimestampwithnotetoallButton  matlab.ui.control.Button
        zeroruntimeButton             matlab.ui.control.Button
        QuitButton                    matlab.ui.control.Button
        Mouse4Panel                   matlab.ui.container.Panel
        minutesLabel_7                matlab.ui.control.Label
        countdownLabel_4              matlab.ui.control.Label
        countdowndurationEditField_4  matlab.ui.control.NumericEditField
        countdowndurationEditField_4Label  matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        StartcountdownButton_4        matlab.ui.control.Button
        noteEditField_4               matlab.ui.control.EditField
        noteEditField_4Label          matlab.ui.control.Label
        AddtimestampwithnoteButton_4  matlab.ui.control.Button
        UITable_4                     matlab.ui.control.Table
        Mouse3Panel                   matlab.ui.container.Panel
        minutesLabel_5                matlab.ui.control.Label
        countdownLabel_3              matlab.ui.control.Label
        countdowndurationEditField_3  matlab.ui.control.NumericEditField
        countdowndurationEditField_3Label  matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        StartcountdownButton_3        matlab.ui.control.Button
        noteEditField_3               matlab.ui.control.EditField
        noteEditField_3Label          matlab.ui.control.Label
        AddtimestampwithnoteButton_3  matlab.ui.control.Button
        UITable_3                     matlab.ui.control.Table
        Mouse2Panel                   matlab.ui.container.Panel
        minutesLabel_3                matlab.ui.control.Label
        countdownLabel_2              matlab.ui.control.Label
        countdowndurationEditField_2  matlab.ui.control.NumericEditField
        countdowndurationEditField_2Label  matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        StartcountdownButton_2        matlab.ui.control.Button
        noteEditField_2               matlab.ui.control.EditField
        noteEditField_2Label          matlab.ui.control.Label
        AddtimestampwithnoteButton_2  matlab.ui.control.Button
        UITable_2                     matlab.ui.control.Table
        runtimeLabel                  matlab.ui.control.Label
        PCtimeLabel                   matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Mouse1Panel                   matlab.ui.container.Panel
        minutesLabel                  matlab.ui.control.Label
        countdownLabel                matlab.ui.control.Label
        countdowndurationEditField    matlab.ui.control.NumericEditField
        countdowndurationLabel        matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        StartcountdownButton          matlab.ui.control.Button
        noteEditField                 matlab.ui.control.EditField
        noteEditFieldLabel            matlab.ui.control.Label
        AddtimestampwithnoteButton    matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        PC_time
        run_time
        note1
        note2
        note3
        note4
        clock_timer
        countdown1
        countdown2
        countdown3
        countdown4
        settingsfile
        save_dir
        save_name
        attribution_str
    end
    
    methods (Access = private)
        
        function updateClock(app,~,~)
            app.PC_time = datestr(now,'HH:MM:SS');
            app.Label.Text = datestr(etime(clock,app.run_time)/(24*60*60),'HH:MM:SS');
            app.Label_3.Text = app.PC_time;
            
            if ~isempty(app.countdown1)
                secondstogo = etime(app.countdown1,clock);
                if secondstogo>120
                    app.Label_2.FontColor = [0 0 0];
                    app.Label_2.Text = datestr(secondstogo/(24*60*60),'HH:MM:SS');
                elseif secondstogo<=120 && secondstogo>0
                    app.Label_2.FontColor = [0.5 0.5 0];
                    app.Label_2.Text = datestr(secondstogo/(24*60*60),'HH:MM:SS');
                else
                    app.countdown1 = [];
                    app.Label_2.FontColor = [0.5 0 0];
                    app.Label_2.Text = '00:00:00';
                    app.note1 = 'countdown finished';
                    AddtimestampwithnoteButtonPushed(app);
                end
            end
            
            if ~isempty(app.countdown2)
                secondstogo = etime(app.countdown2,clock);
                if secondstogo>120
                    app.Label_4.FontColor = [0 0 0];
                    app.Label_4.Text = datestr(secondstogo/(24*60*60),'HH:MM:SS');
                elseif secondstogo<=120 && secondstogo>0
                    app.Label_4.FontColor = [0.5 0.5 0];
                    app.Label_4.Text = datestr(secondstogo/(24*60*60),'HH:MM:SS');
                else
                    app.countdown2 = [];
                    app.Label_4.FontColor = [0.5 0 0];
                    app.Label_4.Text = '00:00:00';
                    app.note2 = 'countdown finished';
                    AddtimestampwithnoteButton_2Pushed(app);
                end
            end
            
            if ~isempty(app.countdown3)
                secondstogo = etime(app.countdown3,clock);
                if secondstogo>120
                    app.Label_5.FontColor = [0 0 0];
                    app.Label_5.Text = datestr(secondstogo/(24*60*60),'HH:MM:SS');
                elseif secondstogo<=120 && secondstogo>0
                    app.Label_5.FontColor = [0.5 0.5 0];
                    app.Label_5.Text = datestr(secondstogo/(24*60*60),'HH:MM:SS');
                else
                    app.countdown3 = [];
                    app.Label_5.FontColor = [0.5 0 0];
                    app.Label_5.Text = '00:00:00';
                    app.note3 = 'countdown finished';
                    AddtimestampwithnoteButton_3Pushed(app);
                end
            end
            
            if ~isempty(app.countdown4)
                secondstogo = etime(app.countdown4,clock);
                if secondstogo>120
                    app.Label_6.FontColor = [0 0 0];
                    app.Label_6.Text = datestr(secondstogo/(24*60*60),'HH:MM:SS');
                elseif secondstogo<=120 && secondstogo>0
                    app.Label_6.FontColor = [0.5 0.5 0];
                    app.Label_6.Text = datestr(secondstogo/(24*60*60),'HH:MM:SS');
                else
                    app.countdown4 = [];
                    app.Label_6.FontColor = [0.5 0 0];
                    app.Label_6.Text = '00:00:00';
                    app.note4 = 'countdown finished';
                    AddtimestampwithnoteButton_4Pushed(app);
                end
            end
        end
        
        function addTimestampAll(app,note)
            app.note1 = note;
            app.note2 = note;
            app.note3 = note;
            app.note4 = note;
            AddtimestampwithnoteButtonPushed(app)
            AddtimestampwithnoteButton_2Pushed(app)
            AddtimestampwithnoteButton_3Pushed(app)
            AddtimestampwithnoteButton_4Pushed(app)
        end
        
        function saveTimestamps(app)
            mouse1 = get(app.UITable,'Data');
            mouse2 = get(app.UITable_2,'Data');
            mouse3 = get(app.UITable_3,'Data');
            mouse4 = get(app.UITable_4,'Data');
            rows = 2+max([size(mouse1,1), size(mouse2,1), size(mouse3,1), size(mouse4,1)]);
            C = cell(rows,15);
            
            rows = size(mouse1,1)+2;
            C(1,1) = {'mouse1'};
            C(2,1:3) = app.UITable.ColumnName;
            C(3:rows,1:3) = mouse1;
            
            rows = size(mouse2,1)+2;
            C(1,5) = {'mouse2'};
            C(2,5:7) = app.UITable_2.ColumnName;
            C(3:rows,5:7) = mouse2;
            
            rows = size(mouse3,1)+2;
            C(1,9) = {'mouse3'};
            C(2,9:11) = app.UITable_3.ColumnName;
            C(3:rows,9:11) = mouse3;
            
            rows = size(mouse4,1)+2;
            C(1,13) = {'mouse4'};
            C(2,13:15) = app.UITable_4.ColumnName;
            C(3:rows,13:15) = mouse4;
            
            writecell(C,fullfile(app.save_dir,[app.save_name '.csv']))
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.attribution_str = 'updated 3/1/2022, Matt Isaacson, mdi22@cornell.edu';
            
            %initialize clock variables
            datenumnow = now;
            app.PC_time = datestr(datenumnow,'HH:MM:SS');
            app.run_time = clock;
            app.Label.Text = '00:00:00';
            app.Label_3.Text = app.PC_time;
            
            %create the file save name
            app.save_name = ['timestamps_' datestr(datenumnow,'yyyy_mm_dd_HH_MM_SS')];
                
            %look for settings file;
            mfilepath = mfilename('fullpath');
            pwdpath = pwd;
            if exist(fullfile(pwdpath,'MultiAnimalTimers.exe'),'file')==2 || exist(fullfile(pwdpath,'MultiAnimalTimers.mlapp'),'file')==2
                app.settingsfile = fullfile(pwdpath,'timer_settings.mat');
            elseif exist([mfilepath '.mlapp'],'file')==2
                mfilepath = fileparts(mfilepath);
                app.settingsfile = fullfile(mfilepath,'timer_settings.mat');
            else
                app.settingsfile = 'C:\timer_settings.mat';
            end
            
            %load the settings file, or create a new one
            if exist(app.settingsfile,'file')==2
                load(app.settingsfile,'save_directory')
                app.save_dir = save_directory;
            else
                [save_directory,~,~] = fileparts(app.settingsfile);
                save_directory = fullfile(save_directory,'saved timestamps');
                save(app.settingsfile,'save_directory')
                app.save_dir = save_directory;
            end
            
            %create the save directory, if it doesn't already exist
            if ~exist(app.save_dir,'dir')
                mkdir(app.save_dir)
            end
            app.savedirectoryLabel.Text = save_directory;
            
            %create and start clock timer
            app.clock_timer = timer('TimerFcn',@app.updateClock,'ExecutionMode','fixedRate','BusyMode','queue');
            start(app.clock_timer);
            
            addTimestampAll(app,'program start')
        end

        % Button pushed function: AddtimestampwithnoteButton
        function AddtimestampwithnoteButtonPushed(app, event)
            if isempty(app.UITable.Data)
                set(app.UITable,'Data',{app.PC_time, app.Label.Text, app.note1})
            else
                set(app.UITable,'Data',[app.UITable.Data; {app.PC_time, app.Label.Text, app.note1}])
            end
            app.note1 = '';
            app.noteEditField.Value = '';
            saveTimestamps(app);
        end

        % Value changed function: noteEditField
        function noteEditFieldValueChanged(app, event)
            app.note1 = app.noteEditField.Value;
        end

        % Button pushed function: StartcountdownButton
        function StartcountdownButtonPushed(app, event)
            durationinminutes = app.countdowndurationEditField.Value;
            app.note1 = [num2str(durationinminutes) ' minute countdown started'];
            app.countdown1 = clock + [0 0 0 0 durationinminutes 0];
            AddtimestampwithnoteButtonPushed(app);
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            stop(app.clock_timer)
            delete(app)
        end

        % Button pushed function: zeroruntimeButton
        function zeroruntimeButtonPushed(app, event)
            app.run_time = clock;
            app.Label.Text = '00:00:00';
            addTimestampAll(app,'run time clock zeroed');
        end

        % Button pushed function: ChangesavedirectoryButton
        function ChangesavedirectoryButtonPushed(app, event)
            save_directory = uigetdir(app.save_dir,'choose a save directory');
            if ischar(save_directory)
                save(app.settingsfile,'save_directory');
                if ~exist(app.save_dir,'dir')
                    mkdir(app.save_dir)
                end
                app.save_dir = save_directory;
                saveTimestamps(app)
                app.savedirectoryLabel.Text = save_directory;
            end
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            msgbox({'This app can be used to log notes with timestamps, relative to both ';...
                'the PC clock and the time the app was started (or was zeroed), for up to ';...
                '4 different mice/experiments/etc simultaneously. Countdowns can also';...
                'be started for each mouse independently, to help keep track of experiment';...
                'timelines. No action is required to save the timestamps -- they are ';...
                'automatically saved to the save directory every time a table is changed.';...
                '';...
                app.attribution_str;...
                ''},'about MultiAnimalTimers');
        end

        % Button pushed function: AddtimestampwithnoteButton_2
        function AddtimestampwithnoteButton_2Pushed(app, event)
            if isempty(app.UITable_2.Data)
                set(app.UITable_2,'Data',{app.PC_time, app.Label.Text, app.note2})
            else
                set(app.UITable_2,'Data',[app.UITable_2.Data; {app.PC_time, app.Label.Text, app.note2}])
            end
            app.note2 = '';
            app.noteEditField_2.Value = '';
            saveTimestamps(app);
        end

        % Button pushed function: AddtimestampwithnoteButton_3
        function AddtimestampwithnoteButton_3Pushed(app, event)
            if isempty(app.UITable_3.Data)
                set(app.UITable_3,'Data',{app.PC_time, app.Label.Text, app.note3})
            else
                set(app.UITable_3,'Data',[app.UITable_3.Data; {app.PC_time, app.Label.Text, app.note3}])
            end
            app.note3 = '';
            app.noteEditField_3.Value = '';
            saveTimestamps(app);
        end

        % Button pushed function: AddtimestampwithnoteButton_4
        function AddtimestampwithnoteButton_4Pushed(app, event)
            if isempty(app.UITable_4.Data)
                set(app.UITable_4,'Data',{app.PC_time, app.Label.Text, app.note4})
            else
                set(app.UITable_4,'Data',[app.UITable_4.Data; {app.PC_time, app.Label.Text, app.note4}])
            end
            app.note4 = '';
            app.noteEditField_4.Value = '';
            saveTimestamps(app);
        end

        % Button pushed function: StartcountdownButton_2
        function StartcountdownButton_2Pushed(app, event)
            durationinminutes = app.countdowndurationEditField_2.Value;
            app.note2 = [num2str(durationinminutes) ' minute countdown started'];
            app.countdown2 = clock + [0 0 0 0 durationinminutes 0];
            AddtimestampwithnoteButton_2Pushed(app);
        end

        % Button pushed function: StartcountdownButton_3
        function StartcountdownButton_3Pushed(app, event)
            durationinminutes = app.countdowndurationEditField_3.Value;
            app.note3 = [num2str(durationinminutes) ' minute countdown started'];
            app.countdown3 = clock + [0 0 0 0 durationinminutes 0];
            AddtimestampwithnoteButton_3Pushed(app);
        end

        % Button pushed function: StartcountdownButton_4
        function StartcountdownButton_4Pushed(app, event)
            durationinminutes = app.countdowndurationEditField_4.Value;
            app.note4 = [num2str(durationinminutes) ' minute countdown started'];
            app.countdown4 = clock + [0 0 0 0 durationinminutes 0];
            AddtimestampwithnoteButton_4Pushed(app);
        end

        % Value changed function: noteEditField_2
        function noteEditField_2ValueChanged(app, event)
            app.note2 = app.noteEditField_2.Value;
        end

        % Value changed function: noteEditField_3
        function noteEditField_3ValueChanged(app, event)
            app.note3 = app.noteEditField_3.Value;
        end

        % Value changed function: noteEditField_4
        function noteEditField_4ValueChanged(app, event)
            app.note4 = app.noteEditField_4.Value;
        end

        % Button pushed function: AddtimestampwithnotetoallButton
        function AddtimestampwithnotetoallButtonPushed(app, event)
            addTimestampAll(app,app.noteEditField_5.Value);
            app.noteEditField_5.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Mouse1Panel = uipanel(app.UIFigure);
            app.Mouse1Panel.Title = 'Mouse 1';
            app.UITable = uitable(app.Mouse1Panel);
            app.UITable.ColumnName = {'PC time'; 'run time'; 'note'};
            app.UITable.RowName = {};
            app.AddtimestampwithnoteButton = uibutton(app.Mouse1Panel, 'push');
            app.AddtimestampwithnoteButton.ButtonPushedFcn = createCallbackFcn(app, @AddtimestampwithnoteButtonPushed, true);
            app.AddtimestampwithnoteButton.Text = {'Add timestamp with note'; ''};
            app.noteEditFieldLabel = uilabel(app.Mouse1Panel);
            app.noteEditFieldLabel.Text = 'note ';
            app.noteEditField = uieditfield(app.Mouse1Panel, 'text');
            app.noteEditField.ValueChangedFcn = createCallbackFcn(app, @noteEditFieldValueChanged, true);
            app.StartcountdownButton = uibutton(app.Mouse1Panel, 'push');
            app.StartcountdownButton.ButtonPushedFcn = createCallbackFcn(app, @StartcountdownButtonPushed, true);
            app.StartcountdownButton.Text = {'Start countdown'; ''};
            app.Label_2 = uilabel(app.Mouse1Panel);
            app.Label_2.Text = '00:00:00';
            app.countdowndurationLabel = uilabel(app.Mouse1Panel);
            app.countdowndurationLabel.Text = 'countdown duration: ';
            app.countdowndurationEditField = uieditfield(app.Mouse1Panel, 'numeric');
            app.countdowndurationEditField.Limits = [0.1 10000];
            app.countdowndurationEditField.Value = 120;
            app.countdownLabel = uilabel(app.Mouse1Panel);
            app.countdownLabel.Text = 'countdown:';
            app.minutesLabel = uilabel(app.Mouse1Panel);
            app.minutesLabel.Text = 'minutes';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '00:00:00';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '00:00:00';
            app.PCtimeLabel = uilabel(app.UIFigure);
            app.PCtimeLabel.Text = 'PC time';
            app.runtimeLabel = uilabel(app.UIFigure);
            app.runtimeLabel.Text = 'run time';
            app.Mouse2Panel = uipanel(app.UIFigure);
            app.Mouse2Panel.Title = 'Mouse 2';
            app.UITable_2 = uitable(app.Mouse2Panel);
            app.UITable_2.ColumnName = {'PC time'; 'run time'; 'note'};
            app.UITable_2.RowName = {};
            app.AddtimestampwithnoteButton_2 = uibutton(app.Mouse2Panel, 'push');
            app.AddtimestampwithnoteButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddtimestampwithnoteButton_2Pushed, true);
            app.AddtimestampwithnoteButton_2.Text = {'Add timestamp with note'; ''};
            app.noteEditField_2Label = uilabel(app.Mouse2Panel);
            app.noteEditField_2Label.Text = 'note ';
            app.noteEditField_2 = uieditfield(app.Mouse2Panel, 'text');
            app.noteEditField_2.ValueChangedFcn = createCallbackFcn(app, @noteEditField_2ValueChanged, true);
            app.StartcountdownButton_2 = uibutton(app.Mouse2Panel, 'push');
            app.StartcountdownButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartcountdownButton_2Pushed, true);
            app.StartcountdownButton_2.Text = 'Start countdown';
            app.Label_4 = uilabel(app.Mouse2Panel);
            app.Label_4.Text = '00:00:00';
            app.countdowndurationEditField_2Label = uilabel(app.Mouse2Panel);
            app.countdowndurationEditField_2Label.Text = 'countdown duration: ';
            app.countdowndurationEditField_2 = uieditfield(app.Mouse2Panel, 'numeric');
            app.countdowndurationEditField_2.Limits = [0.1 10000];
            app.countdowndurationEditField_2.Value = 120;
            app.countdownLabel_2 = uilabel(app.Mouse2Panel);
            app.countdownLabel_2.Text = 'countdown:';
            app.minutesLabel_3 = uilabel(app.Mouse2Panel);
            app.minutesLabel_3.Text = 'minutes';
            app.Mouse3Panel = uipanel(app.UIFigure);
            app.Mouse3Panel.Title = 'Mouse 3';
            app.UITable_3 = uitable(app.Mouse3Panel);
            app.UITable_3.ColumnName = {'PC time'; 'run time'; 'note'};
            app.UITable_3.RowName = {};
            app.AddtimestampwithnoteButton_3 = uibutton(app.Mouse3Panel, 'push');
            app.AddtimestampwithnoteButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddtimestampwithnoteButton_3Pushed, true);
            app.AddtimestampwithnoteButton_3.Text = {'Add timestamp with note'; ''};
            app.noteEditField_3Label = uilabel(app.Mouse3Panel);
            app.noteEditField_3Label.Text = 'note ';
            app.noteEditField_3 = uieditfield(app.Mouse3Panel, 'text');
            app.noteEditField_3.ValueChangedFcn = createCallbackFcn(app, @noteEditField_3ValueChanged, true);
            app.StartcountdownButton_3 = uibutton(app.Mouse3Panel, 'push');
            app.StartcountdownButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartcountdownButton_3Pushed, true);
            app.StartcountdownButton_3.Text = 'Start countdown';
            app.Label_5 = uilabel(app.Mouse3Panel);
            app.Label_5.Text = '00:00:00';
            app.countdowndurationEditField_3Label = uilabel(app.Mouse3Panel);
            app.countdowndurationEditField_3Label.Text = 'countdown duration: ';
            app.countdowndurationEditField_3 = uieditfield(app.Mouse3Panel, 'numeric');
            app.countdowndurationEditField_3.Limits = [0.1 10000];
            app.countdowndurationEditField_3.Value = 120;
            app.countdownLabel_3 = uilabel(app.Mouse3Panel);
            app.countdownLabel_3.Text = 'countdown:';
            app.minutesLabel_5 = uilabel(app.Mouse3Panel);
            app.minutesLabel_5.Text = 'minutes';
            app.Mouse4Panel = uipanel(app.UIFigure);
            app.Mouse4Panel.Title = 'Mouse 4';
            app.UITable_4 = uitable(app.Mouse4Panel);
            app.UITable_4.ColumnName = {'PC time'; 'run time'; 'note'};
            app.UITable_4.RowName = {};
            app.UITable_4.ColumnEditable = true;
            app.AddtimestampwithnoteButton_4 = uibutton(app.Mouse4Panel, 'push');
            app.AddtimestampwithnoteButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddtimestampwithnoteButton_4Pushed, true);
            app.AddtimestampwithnoteButton_4.Text = {'Add timestamp with note'; ''};
            app.noteEditField_4Label = uilabel(app.Mouse4Panel);
            app.noteEditField_4Label.Text = 'note ';
            app.noteEditField_4 = uieditfield(app.Mouse4Panel, 'text');
            app.noteEditField_4.ValueChangedFcn = createCallbackFcn(app, @noteEditField_4ValueChanged, true);
            app.StartcountdownButton_4 = uibutton(app.Mouse4Panel, 'push');
            app.StartcountdownButton_4.ButtonPushedFcn = createCallbackFcn(app, @StartcountdownButton_4Pushed, true);
            app.StartcountdownButton_4.Text = 'Start countdown';
            app.Label_6 = uilabel(app.Mouse4Panel);
            app.Label_6.Text = '00:00:00';
            app.countdowndurationEditField_4Label = uilabel(app.Mouse4Panel);
            app.countdowndurationEditField_4Label.Text = 'countdown duration: ';
            app.countdowndurationEditField_4 = uieditfield(app.Mouse4Panel, 'numeric');
            app.countdowndurationEditField_4.Limits = [0.1 10000];
            app.countdowndurationEditField_4.Value = 120;
            app.countdownLabel_4 = uilabel(app.Mouse4Panel);
            app.countdownLabel_4.Text = 'countdown:';
            app.minutesLabel_7 = uilabel(app.Mouse4Panel);
            app.minutesLabel_7.Text = 'minutes';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.zeroruntimeButton = uibutton(app.UIFigure, 'push');
            app.zeroruntimeButton.ButtonPushedFcn = createCallbackFcn(app, @zeroruntimeButtonPushed, true);
            app.zeroruntimeButton.Text = 'zero run time';
            app.AddtimestampwithnotetoallButton = uibutton(app.UIFigure, 'push');
            app.AddtimestampwithnotetoallButton.ButtonPushedFcn = createCallbackFcn(app, @AddtimestampwithnotetoallButtonPushed, true);
            app.AddtimestampwithnotetoallButton.Text = 'Add timestamp with note to all';
            app.noteEditField_5Label = uilabel(app.UIFigure);
            app.noteEditField_5Label.Text = 'note ';
            app.noteEditField_5 = uieditfield(app.UIFigure, 'text');
            app.ChangesavedirectoryButton = uibutton(app.UIFigure, 'push');
            app.ChangesavedirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChangesavedirectoryButtonPushed, true);
            app.ChangesavedirectoryButton.Text = 'Change save directory';
            app.savedirectoryLabel = uilabel(app.UIFigure);
            app.savedirectoryLabel.Text = 'save directory';
            app.AboutButton = uibutton(app.UIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiAnimalTimers

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end